classdef app_classify_v30 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        BasicPanel                matlab.ui.container.Panel
        CalculateButton           matlab.ui.control.Button
        UnmixButton               matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        ClassificationPanel       matlab.ui.container.Panel
        ResetButton               matlab.ui.control.Button
        BASlider                  matlab.ui.control.Slider
        StainAreaAdjustmentLabel  matlab.ui.control.Label
        MethodDropDown            matlab.ui.control.DropDown
        MethodDropDownLabel       matlab.ui.control.Label
        OthersCheckBox            matlab.ui.control.CheckBox
        ECmucinCheckBox           matlab.ui.control.CheckBox
        LEGHmucinCheckBox         matlab.ui.control.CheckBox
        ClassifyButton            matlab.ui.control.Button
        Lamp_3                    matlab.ui.control.Lamp
        Lamp_2                    matlab.ui.control.Lamp
        Lamp                      matlab.ui.control.Lamp
        UIAxes_2                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        bgX2 % Description
        C2 % Description
        LEGH_img % Description
        EC_img
        back_img % Description
        h
        w % Description
        bandimg % Description
        stain_LEGH % Description
        stain_EC % Description
        stain_back % Description
        Mdl_Fischer3 % Description
        Mdl_Fischer3_Q
        Mdl_Fischer2
        Mdl_Manual
        label_flag % Description
        Dropdown_Value % Description
        gb % Description
    end
    
    methods (Access = private)
        
        
        function g=gammahosei_c(app,g_norm)

            % g_norm = g_norm.^(1/2.2);
            
            g1 = g_norm;
            g2 = g_norm;
            gf1 = g1>0.0031308;
            gf2 = g2<=0.0031308;
            g1(g1>0.0031308) = 1.055*g1(g1>0.0031308).^(1/2.4)-0.055;
            g1 = gf1.*g1;
            g2(g2<=0.0031308) = 12.92*g2(g2<=0.0031308);
            g2 = gf2.*g2;
            
            g = g1 + g2;
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            fname = fullfile(path,file);
            
            band_disp = 3;
            band_disp_r = 10;
            band_disp_g = 5;
            band_disp_b = 2;
            disp_wl = 0.8;
            
            n_bands = 14;   % 14-band image only
            prec_byte = 4;  % 32-bit data only
            hdsz = 4;       % Requesting size for header data
            offs_sz = 183;     % Offset for image size data
            offs_thumb = 192;
            offs_eof = 1568;
            
            fd = fopen( fname, 'rb' );
            [hdata, nrd] = fread( fd, hdsz, 'uint32' );
            % disp( dec2hex( hdata(1:nrd) ) );
            % disp( dec2hex( hdata(1) ) );
            
            fseek( fd, offs_sz, 'bof' );
            [hdata, nrd] = fread( fd, hdsz, 'uint32' );
            
            app.h = hdata(1);
            app.w = hdata(2);
            % prec_byte = hdata(3);  % Data other than 32-bits is not supported
            % fprintf('%d bands, %d x %d, %d bits\n', n_bands, w, h, prec_byte*8 );
            
            imsize = app.h * app.w * n_bands;
            imbytes = imsize * prec_byte;
            
            info = dir(fname);
            % disp(info);
            fsize = info.bytes;
            hsize = fsize - imbytes;
            % fprintf('File size = %d, Image size = %d, Header size = %d\n', fsize, imbytes, hsize );
            
            frewind(fd);
            fseek( fd, offs_thumb, 'bof' );
            [thumbn, cnt] = fread( fd, [app.w app.h], 'uint32' );
            % fprintf('%d data read (Request: %d).\n', cnt, w*h );
            
            frewind(fd);
            fseek( fd, hsize-offs_eof, 'bof' );
            % fseek( fd, offs_thumb+offs_thumb_im+w*h*prec_byte, 'bof' );
            
            [msd, cnt] = fread( fd, imsize, 'float32' );
            msim = reshape( msd, [n_bands app.w app.h]);
            
            fclose( fd );


            app.bandimg=zeros(app.h,app.w,14);
            
            for i=1:14
               bandimg2(:,:,i)=squeeze(msim(i,:,:))';
            end
            
            xyz401=csvread("xyz.csv");
            xyz14=xyz401(71:20:331,2:4)';
            app.bandimg=reshape(bandimg2,app.w*app.h,14);
            
            D65 = csvread("D65.csv");
            D65 = D65(:,2);
            
            for i = 1:14
                ab14 = tabulate(round(app.bandimg(:,i),4));
                [~,bmax] = max(ab14(:,2));
                bint(i) = ab14(bmax,1);
            end
            
            
            msim2=10.^(-msim);
            bandimg3=zeros(app.h,app.w,14);
            for i=1:14
               bandimg4(:,:,i)=squeeze(msim2(i,:,:))';
            end
            bandimg3=reshape(bandimg4,app.w*app.h,14);
            
            
            gxyz=horzcat(sum(bandimg3.*xyz14(1,:).*D65',2),sum(bandimg3.*xyz14(2,:).*D65',2),sum(bandimg3.*xyz14(3,:).*D65',2));
            gxyzN = sum(xyz14(2,:).*D65');
            gXYZ = gxyz./gxyzN;


            wp_d65 = whitepoint('d65');
            wXYZ(1) = prctile(gXYZ(:,1),99.99);
            wXYZ(2) = prctile(gXYZ(:,2),99.99);
            wXYZ(3) = prctile(gXYZ(:,3),99.99);
            wratio = wp_d65./wXYZ;
            
            gX = gXYZ(:,1)*wratio(1);
            gY = gXYZ(:,2)*wratio(2);
            gZ = gXYZ(:,3)*wratio(3);
            gXYZ = [gX,gY,gZ];
            
            
            xyz2srgbmat=[ 3.2404542 -1.5371385 -0.4985314;...
                     -0.9692660  1.8520108  0.0415560;...
                      0.0556434 -0.2040259  1.0572276];              
            
            
            sRGB=(xyz2srgbmat*gXYZ')';
            bgX1=gammahosei_c(app,sRGB);


            bgX1(bgX1<0) = 0;
            bgX1(bgX1>1) = 1;
            
            
            app.bgX2 = reshape(bgX1,app.h,app.w,3);

            imshow(app.bgX2,'Parent',app.UIAxes);
            
            cla(app.UIAxes_2);
            app.UnmixButton.Enable = 1;
            app.ClassifyButton.Enable = 0;
            app.CalculateButton.Enable = 0;
            app.LEGHmucinCheckBox.Value = 1;
            app.LEGHmucinCheckBox.Enable = 0;
            app.ECmucinCheckBox.Value = 0;
            app.ECmucinCheckBox.Enable = 0;
            app.OthersCheckBox.Value = 0;
            app.OthersCheckBox.Enable = 0;
            app.MethodDropDown.Enable = 0;
            app.BASlider.Enable = 0;
            app.ResetButton.Enable = 0;
        end

        % Button pushed function: UnmixButton
        function UnmixButtonPushed(app, event)

            H = zeros(4,14);
            Ha=csvread("Ha_0823.csv");
            
            H14=Ha(1:14,3:6)';
            
            H(1,:)=H14(1,:);
            H(2,:)=H14(2,:);
            H(3,:)=H14(3,:);
            H(4,:)=H14(4,:);
            
            
            Hinv = pinv(H);
            app.C2 = app.bandimg*Hinv;
            app.C2(app.C2<0) = 0;
            C = reshape(app.C2,[app.h,app.w,4]);
            
            s0 = svd(H);
            num = cond(H);
            
            eosin = C(:,:,1);
            hema = C(:,:,2);
            LG = C(:,:,3);
            OG = C(:,:,4);
            
            h1 = figure;
            imshow(app.bgX2);
            rect = getrect;
            close(h1);
            %[xi,yi] = getpts;
            X = rect(1,1);
            Y = rect(1,2);
            width = rect(1,3);
            height = rect(1,4);
            X = round(X);
            Y = round(Y);
            width = round(width);
            height = round(height);
            X2 = X + width;
            Y2 = Y + height;
            
            gray = rgb2gray(app.bgX2);
            gray_back = gray(Y:Y2, X:X2);
            app.gb = round(mean(gray_back,'all'),2);

            M1 = eosin(Y:Y2, X:X2);
            r=reshape(M1,[(width+1)*(height+1),1]);
            triE=prctile(r,99.99);
            eosin(eosin<triE)=0.0;
            
            M2 = hema(Y:Y2, X:X2);
            r=reshape(M2,[(width+1)*(height+1),1]);
            triH=prctile(r,99.99);
            hema(hema<triH)=0.0;
            
            M3 = LG(Y:Y2, X:X2);
            r=reshape(M3,[(width+1)*(height+1),1]);
            triL=prctile(r,99.99);
            LG(LG<triL)=0.0;
            
            M4 = OG(Y:Y2, X:X2);
            r=reshape(M4,[(width+1)*(height+1),1]);
            triO=prctile(r,99.99);
            OG(OG<triO)=0.0;
            

            eosin(:,:)=eosin(:,:)*0.499350650005020;
            hema(:,:)=hema(:,:)*0.529574866150920;
            LG(:,:)=LG(:,:)*0.392146052341812;
            OG(:,:)=OG(:,:)*0.823913588791684;


            r=reshape(eosin,[app.w*app.h,1]);
            eosinmax=prctile(r,99.99);
            if eosinmax<1
                eosinmax=1;
            end
            
            r=reshape(hema,[app.w*app.h,1]);
            hemax=prctile(r,99.99);
            if hemax<1
                hemax=1;
            end
            
            r=reshape(LG,[app.w*app.h,1]);
            LGmax=prctile(r,99.99);
            if LGmax<1
                LGmax=1;
            end
            
            r=reshape(OG,[app.w*app.h,1]);
            OGmax=prctile(r,99.9);
            if OGmax<1
                OGmax=1;
            end
            
            low=0;
            
            figure;
            subplot(2,2,1), imshow(eosin,[low eosinmax]);
            title('eosin');
            colorbar;
            subplot(2,2,2), imshow(hema,[low hemax]);
            title('hema');
            colorbar;
            subplot(2,2,3), imshow(LG,[low LGmax]);
            title('LG');
            colorbar;
            subplot(2,2,4), imshow(OG,[low OGmax]);
            title('OG');
            sgtitle('Color Unmixing Result');
            colorbar;
            
            app.C2 = [eosin(:),hema(:),LG(:),OG(:)];
            app.ClassifyButton.Enable = 1;
            app.CalculateButton.Enable = 1;


            %----------------------------------------------------------------Initialization for classification-----------------------------------------------------------
            load('Fischer_3.mat');
            app.Mdl_Fischer3 = MdlLinear;
            app.Mdl_Fischer3_Q = MdlQuadratic;
            load('Fischer_2.mat');
            app.Mdl_Fischer2 = MdlLinear;
            load('Manual.mat');
            app.Mdl_Manual = ab;

            v1 = app.LEGHmucinCheckBox.Value;
            v2 = app.ECmucinCheckBox.Value;
            v3 = app.OthersCheckBox.Value;
            app.label_flag = [v1 v2 v3];

            app.Dropdown_Value = app.MethodDropDown.Value;

            app.MethodDropDown.Enable = 1;
            app.BASlider.Value = 100*app.gb-5;
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Processing...');

            C = reshape(app.C2,app.h,app.w,4);
            h_ave = fspecial('average',10);
            C_ave = imfilter(C,h_ave,'replicate');
            app.C2 = reshape(C_ave,app.w*app.h,4);

            gray = rgb2gray(app.bgX2);
            stain = gray<app.BASlider.Value/100;

            switch app.Dropdown_Value
                case 'Eosin&OG&LG_Linear'
                    index = predict(app.Mdl_Fischer3,app.C2(:,[1,3,4]));
                    index_LEGH = strcmp(index,'LEGH');
                    index_LEGH2 = reshape(index_LEGH,app.h,app.w);
                    index_EC = strcmp(index,'EC');
                    index_EC2 = reshape(index_EC,app.h,app.w);
                case 'Eosin&OG&LG_Nonlinear'
                    index = predict(app.Mdl_Fischer3_Q,app.C2(:,[1,3,4]));
                    index_LEGH = strcmp(index,'LEGH');
                    index_LEGH2 = reshape(index_LEGH,app.h,app.w);
                    index_EC = strcmp(index,'EC');
                    index_EC2 = reshape(index_EC,app.h,app.w);
                case 'Eosin&OG_Linear'
                    index = predict(app.Mdl_Fischer2,app.C2(:,[1,4]));
                    index_LEGH = strcmp(index,'LEGH');
                    index_LEGH2 = reshape(index_LEGH,app.h,app.w);
                    index_EC = strcmp(index,'EC');
                    index_EC2 = reshape(index_EC,app.h,app.w);
                case 'Eosin&OG_Manual'
                    Est = @(x)(x(3,:)>0.005) .* (x(3,:)> app.Mdl_Manual(1)*x(1,:)+app.Mdl_Manual(2));
                    index_LEGH = Est(app.C2(:,[1,3,4])');
                    index_LEGH2 = reshape(index_LEGH,app.h,app.w);
                    index_EC = ~index_LEGH;
                    index_EC2 = reshape(index_EC,app.h,app.w);
            end

            app.stain_EC = index_EC2.*stain;
            app.stain_LEGH = index_LEGH2.*stain;
            app.stain_back = ~stain;
            
            map = [138/255,43/255,226/255;1,1,0;0.07,0.62,1.00];
            app.EC_img(:,:,1) = map(1,1).*(app.stain_EC);
            app.EC_img(:,:,2) = map(1,2).*(app.stain_EC);
            app.EC_img(:,:,3) = map(1,3).*(app.stain_EC);
            app.LEGH_img(:,:,1) = map(2,1).*(app.stain_LEGH);
            app.LEGH_img(:,:,2) = map(2,2).*(app.stain_LEGH);
            app.LEGH_img(:,:,3) = map(2,3).*(app.stain_LEGH);
            app.back_img(:,:,1) = map(3,1).*(app.stain_back);
            app.back_img(:,:,2) = map(3,2).*(app.stain_back);
            app.back_img(:,:,3) = map(3,3).*(app.stain_back);
            img1 = imadd(0.25*app.label_flag(1)*app.LEGH_img,app.bgX2);
            img2 = imadd(0.25*app.label_flag(2)*app.EC_img,0.5*app.label_flag(3)*app.back_img);
            img = imadd(img1,img2);
            
            imshow(img,'Parent',app.UIAxes_2);


            close(d);
            close(f);

            app.LEGHmucinCheckBox.Enable = 1;
            app.ECmucinCheckBox.Enable = 1;
            app.OthersCheckBox.Enable = 1;
            app.OthersCheckBox.Enable = 1;
            app.ECmucinCheckBox.Enable = 1;
            app.LEGHmucinCheckBox.Enable = 1;
            app.BASlider.Enable = 1;
            app.ResetButton.Enable = 1;
        end

        % Value changed function: ECmucinCheckBox, LEGHmucinCheckBox, 
        % ...and 1 other component
        function StainCheckBoxValueChanged(app, event)
            v1 = app.LEGHmucinCheckBox.Value;
            v2 = app.ECmucinCheckBox.Value;
            v3 = app.OthersCheckBox.Value;
            app.label_flag = [v1 v2 v3];
            
            img1 = imadd(0.25*app.label_flag(1)*app.LEGH_img,app.bgX2);
            img2 = imadd(0.25*app.label_flag(2)*app.EC_img,0.5*app.label_flag(3)*app.back_img);
            img = imadd(img1,img2);
            
            imshow(img,'Parent',app.UIAxes_2);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            figure()
            imshow(app.bgX2);
            roi = drawpolygon;
            xypoly = roi.Position;
            x = 1:app.w;
            y = 1:app.h;
            [xq,yq] = meshgrid(x,y);
            inpoly = inpolygon(xq(:),yq(:),xypoly(:,1),xypoly(:,2));

            roi4 = app.C2(inpoly,:);
            roi4_mean = round(mean(roi4),3);

            label = {'eosin';'hema';'LG';'OG'};
            figure()
            h_bar = bar(diag(roi4_mean),'stack');
            set(gca,  'XTickLabel',label ,'XTick',1:length(label) ,'XLim',[0,length(label)+1]);
            h_bar(1).FaceColor = [1 .5 1];
            h_bar(2).FaceColor = [.5 0 1];
            h_bar(3).FaceColor = [0 .8 0.8];
            h_bar(4).FaceColor = [1 .5 0];

            ax = gca;
            ax.XAxis.FontSize = 14;
            ax.YAxis.FontSize = 14;
            
            xt = [0.75 1.75 2.75 3.75];
            aa = roi4_mean==0;
            xxt = [1,2,3,4];
            xt(aa) = xxt(aa);

            Limit = ax.YLim;
            Memori = ax.YTick;
            NAMB = nnz(Memori);
            Limit = max(Limit);
            PAR = Limit/NAMB;
            PAR2 = PAR/2.5;
            if Limit<1
                Limit=1;
            end
            ax.YLim = [0 Limit];
            yt = [roi4_mean(1)+PAR2 roi4_mean(2)+PAR2 roi4_mean(3)+PAR2 roi4_mean(4)+PAR2];
            str = string(roi4_mean);
            str = cellstr(str);
            text(xt,yt,str,'FontSize',16);
            grid on
            title('Stain Density Result');
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.Dropdown_Value = app.MethodDropDown.Value;

            app.OthersCheckBox.Enable = 0;
            app.ECmucinCheckBox.Enable = 0;
            app.LEGHmucinCheckBox.Enable = 0;
        end

        % Value changed function: BASlider
        function BASliderValueChanged(app, event)

            ClassifyButtonPushed(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.BASlider.Value = 100*app.gb-5;
            ClassifyButtonPushed(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {700, 700};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {705, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Original')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BasicPanel = uipanel(app.LeftPanel);
            app.BasicPanel.Title = 'Basic';
            app.LoadButton = uibutton(app.BasicPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UnmixButton = uibutton(app.BasicPanel, 'push');
            app.UnmixButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixButtonPushed, true);
            app.UnmixButton.Enable = 'off';
            app.UnmixButton.Text = 'Unmix';
            app.CalculateButton = uibutton(app.BasicPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = {'Calculate '; 'stain density'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Classified')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ClassificationPanel = uipanel(app.RightPanel);
            app.ClassificationPanel.Title = 'Classification';
            app.Lamp = uilamp(app.ClassificationPanel);
            app.Lamp_2 = uilamp(app.ClassificationPanel);
            app.Lamp_3 = uilamp(app.ClassificationPanel);
            app.ClassifyButton = uibutton(app.ClassificationPanel, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Enable = 'off';
            app.ClassifyButton.Text = 'Classify';
            app.LEGHmucinCheckBox = uicheckbox(app.ClassificationPanel);
            app.LEGHmucinCheckBox.ValueChangedFcn = createCallbackFcn(app, @StainCheckBoxValueChanged, true);
            app.LEGHmucinCheckBox.Tag = 'stain';
            app.LEGHmucinCheckBox.Enable = 'off';
            app.LEGHmucinCheckBox.Text = 'LEGH mucin';
            app.LEGHmucinCheckBox.Value = true;
            app.ECmucinCheckBox = uicheckbox(app.ClassificationPanel);
            app.ECmucinCheckBox.ValueChangedFcn = createCallbackFcn(app, @StainCheckBoxValueChanged, true);
            app.ECmucinCheckBox.Tag = 'stain';
            app.ECmucinCheckBox.Enable = 'off';
            app.ECmucinCheckBox.Text = 'EC mucin';
            app.OthersCheckBox = uicheckbox(app.ClassificationPanel);
            app.OthersCheckBox.ValueChangedFcn = createCallbackFcn(app, @StainCheckBoxValueChanged, true);
            app.OthersCheckBox.Tag = 'stain';
            app.OthersCheckBox.Enable = 'off';
            app.OthersCheckBox.Text = 'Others';
            app.MethodDropDownLabel = uilabel(app.ClassificationPanel);
            app.MethodDropDownLabel.Enable = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.ClassificationPanel);
            app.MethodDropDown.Items = {'Eosin&OG_Manual', 'Eosin&OG_Linear', 'Eosin&OG&LG_Linear', 'Eosin&OG&LG_Nonlinear'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Enable = 'off';
            app.MethodDropDown.Value = 'Eosin&OG&LG_Linear';
            app.StainAreaAdjustmentLabel = uilabel(app.ClassificationPanel);
            app.StainAreaAdjustmentLabel.Enable = 'off';
            app.StainAreaAdjustmentLabel.Text = {'Stain Area'; 'Adjustment'};
            app.BASlider = uislider(app.ClassificationPanel);
            app.BASlider.Limits = [50 100];
            app.BASlider.ValueChangedFcn = createCallbackFcn(app, @BASliderValueChanged, true);
            app.BASlider.Enable = 'off';
            app.BASlider.Value = 80;
            app.ResetButton = uibutton(app.ClassificationPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_classify_v30

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end